classdef LowPassFilter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LowPassFilterCalculatorUIFigure  matlab.ui.Figure
        ResistanceinkOhmPanel  matlab.ui.container.Panel
        REditFieldLabel        matlab.ui.control.Label
        REdit                  matlab.ui.control.NumericEditField
        Rbutton1               matlab.ui.control.Button
        Rbutton2               matlab.ui.control.Button
        Rbutton3               matlab.ui.control.Button
        REnter                 matlab.ui.control.Button
        CapacitanceinuFPanel   matlab.ui.container.Panel
        CEditFieldLabel        matlab.ui.control.Label
        CEdit                  matlab.ui.control.NumericEditField
        Cbutton1               matlab.ui.control.Button
        Cbutton2               matlab.ui.control.Button
        Cbutton3               matlab.ui.control.Button
        CEnter                 matlab.ui.control.Button
        Image                  matlab.ui.control.Image
        fcLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        capacitance = 0.01; % uF
        resistance = 1; % kOhm
    end
    
    methods (Access = private)
        
        % set the cutoff frequency
        function setFC(app)
            fc = 1/(2*pi*(app.resistance*10^3)*(app.capacitance*10^(-6)));
            app.fcLabel.Text = sprintf("fc = %5.0f Hz",fc);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Rbutton1Pushed();
            app.Cbutton1ButtonPushed();
        end

        % Button pushed function: Rbutton1
        function Rbutton1Pushed(app, event)
            app.resistance = str2num(app.Rbutton1.Text);
            app.setFC();
            app.REdit.Value = 0;
            app.Rbutton1.BackgroundColor = [0.65 0.65 0.65];
            app.Rbutton2.BackgroundColor = [0.96 0.96 0.96];
            app.Rbutton3.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Rbutton2
        function Rbutton2Pushed(app, event)
            app.resistance = str2num(app.Rbutton2.Text);
            app.setFC();
            app.REdit.Value = 0;
            app.Rbutton2.BackgroundColor = [0.65 0.65 0.65];
            app.Rbutton1.BackgroundColor = [0.96 0.96 0.96];
            app.Rbutton3.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Rbutton3
        function Rbutton3Pushed(app, event)
            app.resistance = str2num(app.Rbutton3.Text);
            app.setFC();
            app.REdit.Value = 0;
            app.Rbutton3.BackgroundColor = [0.65 0.65 0.65];
            app.Rbutton2.BackgroundColor = [0.96 0.96 0.96];
            app.Rbutton1.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Cbutton1
        function Cbutton1ButtonPushed(app, event)
            app.capacitance = str2num(app.Cbutton1.Text);
            app.setFC();
            app.CEdit.Value = 0;
            app.Cbutton1.BackgroundColor = [0.65 0.65 0.65];
            app.Cbutton2.BackgroundColor = [0.96 0.96 0.96];
            app.Cbutton3.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Cbutton2
        function Cbutton2ButtonPushed(app, event)
            app.capacitance = str2num(app.Cbutton2.Text);
            app.setFC();
            app.CEdit.Value = 0;
            app.Cbutton2.BackgroundColor = [0.65 0.65 0.65];
            app.Cbutton1.BackgroundColor = [0.96 0.96 0.96];
            app.Cbutton3.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: Cbutton3
        function Cbutton3ButtonPushed(app, event)
            app.capacitance = str2num(app.Cbutton3.Text);
            app.setFC();
            app.CEdit.Value = 0;
            app.Cbutton3.BackgroundColor = [0.65 0.65 0.65];
            app.Cbutton1.BackgroundColor = [0.96 0.96 0.96];
            app.Cbutton2.BackgroundColor = [0.96 0.96 0.96];
        end

        % Button pushed function: REnter
        function RREnterPushed(app, event)
            app.Rbutton3.BackgroundColor = [0.96 0.96 0.96];
            app.Rbutton2.BackgroundColor = [0.96 0.96 0.96];
            app.Rbutton1.BackgroundColor = [0.96 0.96 0.96];
            app.resistance = app.REdit.Value;
            app.setFC();
        end

        % Button pushed function: CEnter
        function CEnterButtonPushed(app, event)
            app.Cbutton3.BackgroundColor = [0.96 0.96 0.96];
            app.Cbutton2.BackgroundColor = [0.96 0.96 0.96];
            app.Cbutton1.BackgroundColor = [0.96 0.96 0.96];
            app.capacitance = app.CEdit.Value;
            app.setFC();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LowPassFilterCalculatorUIFigure = uifigure('Visible', 'off');
            app.LowPassFilterCalculatorUIFigure.Name = 'Low Pass Filter Calculator';
            app.ResistanceinkOhmPanel = uipanel(app.LowPassFilterCalculatorUIFigure);
            app.ResistanceinkOhmPanel.Title = 'Resistance (in kOhm)';
            app.REditFieldLabel = uilabel(app.ResistanceinkOhmPanel);
            app.REditFieldLabel.Text = 'R = ';
            app.REdit = uieditfield(app.ResistanceinkOhmPanel, 'numeric');
            app.Rbutton1 = uibutton(app.ResistanceinkOhmPanel, 'push');
            app.Rbutton1.ButtonPushedFcn = createCallbackFcn(app, @Rbutton1Pushed, true);
            app.Rbutton1.Text = '1';
            app.Rbutton2 = uibutton(app.ResistanceinkOhmPanel, 'push');
            app.Rbutton2.ButtonPushedFcn = createCallbackFcn(app, @Rbutton2Pushed, true);
            app.Rbutton2.Text = '4.7';
            app.Rbutton3 = uibutton(app.ResistanceinkOhmPanel, 'push');
            app.Rbutton3.ButtonPushedFcn = createCallbackFcn(app, @Rbutton3Pushed, true);
            app.Rbutton3.Text = '10';
            app.REnter = uibutton(app.ResistanceinkOhmPanel, 'push');
            app.REnter.ButtonPushedFcn = createCallbackFcn(app, @RREnterPushed, true);
            app.REnter.Text = 'Enter';
            app.CapacitanceinuFPanel = uipanel(app.LowPassFilterCalculatorUIFigure);
            app.CapacitanceinuFPanel.Title = 'Capacitance (in uF)';
            app.CEditFieldLabel = uilabel(app.CapacitanceinuFPanel);
            app.CEditFieldLabel.Text = 'C = ';
            app.CEdit = uieditfield(app.CapacitanceinuFPanel, 'numeric');
            app.Cbutton1 = uibutton(app.CapacitanceinuFPanel, 'push');
            app.Cbutton1.ButtonPushedFcn = createCallbackFcn(app, @Cbutton1ButtonPushed, true);
            app.Cbutton1.Text = '0.01';
            app.Cbutton2 = uibutton(app.CapacitanceinuFPanel, 'push');
            app.Cbutton2.ButtonPushedFcn = createCallbackFcn(app, @Cbutton2ButtonPushed, true);
            app.Cbutton2.Text = '0.1';
            app.Cbutton3 = uibutton(app.CapacitanceinuFPanel, 'push');
            app.Cbutton3.ButtonPushedFcn = createCallbackFcn(app, @Cbutton3ButtonPushed, true);
            app.Cbutton3.Text = '1';
            app.CEnter = uibutton(app.CapacitanceinuFPanel, 'push');
            app.CEnter.ButtonPushedFcn = createCallbackFcn(app, @CEnterButtonPushed, true);
            app.CEnter.Text = 'Enter';
            app.Image = uiimage(app.LowPassFilterCalculatorUIFigure);
            app.Image.ImageSource = 'circuit_image.png';
            app.fcLabel = uilabel(app.LowPassFilterCalculatorUIFigure);
            app.fcLabel.Text = 'fc = 15915 Hz';
            % Show the figure after all components are created
            app.LowPassFilterCalculatorUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LowPassFilter

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LowPassFilterCalculatorUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LowPassFilterCalculatorUIFigure)
        end
    end
end