classdef CommMatlab < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        EagleUIFigure             matlab.ui.Figure
        LEDButtonGroup            matlab.ui.container.ButtonGroup
        OFFButton                 matlab.ui.control.ToggleButton
        ONButton                  matlab.ui.control.ToggleButton
        ButtonCounterLabel        matlab.ui.control.Label
        CountLabel                matlab.ui.control.Label
        ServosliderLabel          matlab.ui.control.Label
        ServosliderSlider         matlab.ui.control.Slider
        ServovalueEditFieldLabel  matlab.ui.control.Label
        ServovalueEditField       matlab.ui.control.NumericEditField
        SendservovalueButton      matlab.ui.control.Button
        Image                     matlab.ui.control.Image
    end

    
    properties (Access = private)
        counter = 0;
        serialObj;
    end
    
    methods (Access = private)
        
        function setDisplay(app)
            app.CountLabel.Text = sprintf("Count = %d",app.counter);
        end
        
        function serialObjCallback(app, serialObj, eventData)
            message = strtrim(readline(serialObj))
            
            if (strcmpi(message, "B"))
                app.counter = app.counter + 1;
                app.setDisplay();
            end
            
            % parameters (joystick)
            arr = split(message);
            if (strcmpi(arr(1),"X"))
                xPos = str2double(arr(2));
                app.Image.Position(1) = round(xPos / 2);
            end
            if (strcmpi(arr(1),"Y"))
                yPos = str2double(arr(2));
                app.Image.Position(2) = (1023/2) - round(yPos / 2);
            end
            
        end
        
        
        function sendServoValue(app,degrees)
            degrees = round(degrees); % need an int
            cmd = sprintf("SERVO %d",degrees);
            writeline(app.serialObj,cmd);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
            app.serialObj = serialport("COM6",9600);
            flush(app.serialObj);
            configureCallback(app.serialObj,"terminator",@app.serialObjCallback);
        end

        % Selection changed function: LEDButtonGroup
        function LEDButtonGroupSelectionChanged(app, event)
            selectedButton = app.LEDButtonGroup.SelectedObject;
            
            if selectedButton == app.OFFButton
                writeline(app.serialObj,"LED OFF");
            end
            if selectedButton == app.ONButton
                writeline(app.serialObj,"LED ON");
            end
        end

        % Value changed function: ServosliderSlider
        function ServosliderSliderValueChanged(app, event)
            value = app.ServosliderSlider.Value;
            app.sendServoValue(value);
        end

        % Value changing function: ServosliderSlider
        function ServosliderSliderValueChanging(app, event)
            changingValue = event.Value;
            app.sendServoValue(changingValue);
        end

        % Button pushed function: SendservovalueButton
        function SendservovalueButtonPushed(app, event)
            value = app.ServovalueEditField.Value;
            app.sendServoValue(value);
            app.ServosliderSlider.Value = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.EagleUIFigure = uifigure('Visible', 'off');
            app.EagleUIFigure.Name = 'Eagle';
            app.LEDButtonGroup = uibuttongroup(app.EagleUIFigure);
            app.LEDButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @LEDButtonGroupSelectionChanged, true);
            app.LEDButtonGroup.Title = 'LED';
            app.OFFButton = uitogglebutton(app.LEDButtonGroup);
            app.OFFButton.Text = 'OFF';
            app.OFFButton.Value = true;
            app.ONButton = uitogglebutton(app.LEDButtonGroup);
            app.ONButton.Text = 'ON';
            app.ButtonCounterLabel = uilabel(app.EagleUIFigure);
            app.ButtonCounterLabel.Text = 'Button Counter:';
            app.CountLabel = uilabel(app.EagleUIFigure);
            app.CountLabel.Text = 'Count = 0';
            app.ServosliderLabel = uilabel(app.EagleUIFigure);
            app.ServosliderLabel.Text = {'Servo'; 'slider'};
            app.ServosliderSlider = uislider(app.EagleUIFigure);
            app.ServosliderSlider.Limits = [0 180];
            app.ServosliderSlider.ValueChangedFcn = createCallbackFcn(app, @ServosliderSliderValueChanged, true);
            app.ServosliderSlider.ValueChangingFcn = createCallbackFcn(app, @ServosliderSliderValueChanging, true);
            app.ServovalueEditFieldLabel = uilabel(app.EagleUIFigure);
            app.ServovalueEditFieldLabel.Text = {'Servo'; 'value'};
            app.ServovalueEditField = uieditfield(app.EagleUIFigure, 'numeric');
            app.SendservovalueButton = uibutton(app.EagleUIFigure, 'push');
            app.SendservovalueButton.ButtonPushedFcn = createCallbackFcn(app, @SendservovalueButtonPushed, true);
            app.SendservovalueButton.Text = 'Send servo value';
            app.Image = uiimage(app.EagleUIFigure);
            app.Image.ImageSource = 'eagle.png';
            % Show the figure after all components are created
            app.EagleUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CommMatlab

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.EagleUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.EagleUIFigure)
        end
    end
end