classdef HelloButton < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        HelloButtonUIFigure  matlab.ui.Figure
        ResetButton          matlab.ui.control.Button
        DecrementButton      matlab.ui.control.Button
        IncrementButton      matlab.ui.control.Button
        Count0Label          matlab.ui.control.Label
    end

    
    properties (Access = private)
        counter = 0;
        serialObj;
    end
    
    methods (Access = private)
        
        function setDisplay(app)
            app.Count0Label.Text = sprintf("Count = %d",app.counter);
        end
        
%         function serialObjCallback(app, serialObj, eventData)
%             message = strtrim(readline(serialObj))
%             
%             if (strcmpi(message, "B"))
%                 app.counter = app.counter + 1;
%                 app.setDisplay();
%             end
%             
%         end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clc;
%             app.serialObj = serialport("COM6",9600);
%             flush(app.serialObj);
%             configureCallback(app.serialObj,"terminator",@app.serialObjCallback);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            app.counter = 0;
            app.setDisplay();
        end

        % Button pushed function: IncrementButton
        function IncrementButtonPushed(app, event)
            app.counter = app.counter + 1;
            fprintf("Count = %d\n",app.counter);
%             writeline(app.serialObj,"LED ON");
            app.setDisplay();
        end

        % Button pushed function: DecrementButton
        function DecrementButtonPushed(app, event)
            app.counter = app.counter - 1;
            fprintf("Count = %d\n",app.counter);
%             writeline(app.serialObj,"LED OFF");
            app.setDisplay();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.HelloButtonUIFigure = uifigure('Visible', 'off');
            app.HelloButtonUIFigure.Name = 'HelloButton';
            app.ResetButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.DecrementButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.DecrementButton.ButtonPushedFcn = createCallbackFcn(app, @DecrementButtonPushed, true);
            app.DecrementButton.Text = 'Decrement';
            app.IncrementButton = uibutton(app.HelloButtonUIFigure, 'push');
            app.IncrementButton.ButtonPushedFcn = createCallbackFcn(app, @IncrementButtonPushed, true);
            app.IncrementButton.Text = 'Increment';
            app.Count0Label = uilabel(app.HelloButtonUIFigure);
            app.Count0Label.Text = 'Count = 0';
            % Show the figure after all components are created
            app.HelloButtonUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = HelloButton

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.HelloButtonUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.HelloButtonUIFigure)
        end
    end
end